classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ControlPanel               matlab.ui.container.Panel
        StopGameButton             matlab.ui.control.Button
        StartGameButton            matlab.ui.control.Button
        MoveManuallyPanel          matlab.ui.container.Panel
        StackORefButton            matlab.ui.control.Button
        StackXRefButton            matlab.ui.control.Button
        LoadReferencesButton       matlab.ui.control.Button
        LeftCornerRefButton        matlab.ui.control.Button
        RightCornerRefButton       matlab.ui.control.Button
        MovetoAnglesButton         matlab.ui.control.Button
        q4EditField                matlab.ui.control.NumericEditField
        q4EditFieldLabel           matlab.ui.control.Label
        q3EditField                matlab.ui.control.NumericEditField
        q3EditFieldLabel           matlab.ui.control.Label
        q5EditField                matlab.ui.control.NumericEditField
        q5EditFieldLabel           matlab.ui.control.Label
        q2EditField                matlab.ui.control.NumericEditField
        q2EditFieldLabel           matlab.ui.control.Label
        q1EditField                matlab.ui.control.NumericEditField
        q1EditFieldLabel           matlab.ui.control.Label
        MovetoCoordinatesButton    matlab.ui.control.Button
        zEditField                 matlab.ui.control.NumericEditField
        zEditFieldLabel            matlab.ui.control.Label
        yEditField                 matlab.ui.control.NumericEditField
        yEditFieldLabel            matlab.ui.control.Label
        xEditField                 matlab.ui.control.NumericEditField
        xEditFieldLabel            matlab.ui.control.Label
        AutoplayOSwitch            matlab.ui.control.Switch
        AutoplayOSwitchLabel       matlab.ui.control.Label
        LoadSimulinkButton         matlab.ui.control.Button
        AutoplayXSwitch            matlab.ui.control.Switch
        AutoplayXSwitchLabel       matlab.ui.control.Label
        ResetGameButton            matlab.ui.control.Button
        ItisyourturnPanel          matlab.ui.container.Panel
        TurnIndicator              matlab.ui.control.Image
        WinnerPanel                matlab.ui.container.Panel
        TheWinnerIsEditField       matlab.ui.control.EditField
        TheWinnerIsEditFieldLabel  matlab.ui.control.Label
        WinnerFoundLamp            matlab.ui.control.Lamp
        WinnerFoundLampLabel       matlab.ui.control.Label
        TicTacToePanel             matlab.ui.container.Panel
        Position9                  matlab.ui.control.Button
        Position8                  matlab.ui.control.Button
        Position7                  matlab.ui.control.Button
        Position6                  matlab.ui.control.Button
        Position5                  matlab.ui.control.Button
        Position4                  matlab.ui.control.Button
        Position3                  matlab.ui.control.Button
        Position2                  matlab.ui.control.Button
        Position1                  matlab.ui.control.Button
    end


    properties (Access = private)
        counter = 0;
        status = ["0" "0" "0" "0" "0" "0" "0" "0" "0"];
        buttonsGame = [];
        position = [[1 1]; [1 2]; [1 3]; [2 1]; [2 2]; [2 3]; [3 1]; [3 2]; [3 3]]
        %coordinatesOrigin = [[1 -1]; [1 0]; [1 1]; [0 -1]; [0 0]; [0 1]; [-1 -1]; [-1 0]; [-1 1]] %%Cordinates for placing on the field
        coordinatesOrigin = [[0.30 0.07]; [0.30 0] ;[0.30 -0.07]; [0.25 0.07]; [0.25 0]; [0.25 -0.07]; [0.20 0.07]; [0.20 0]; [0.20 -0.07]] %%Cordinates for placing on the field
        winningPositionsX1 = [1, 1, 1]
        winningPositionsX2 = [1; 1; 1];
        winningPositionsO1 = [2, 2, 2]
        winningPositionsO2 = [2; 2; 2];
        positionsX = zeros(3,3);
        positionsO = zeros(3,3);
        winner = 0;

        %armStillMoving = 0;

        stopGame = 0;
        autoplayO = "off";
        autoplayX = "off";


        rightCornerUpX = 1.5;
        rightCornerUpY = 1.5;

        leftCornerLowX = -1.5;
        leftCornerLowY = -1.5

        rightCornerUpXMod = 1.5;
        rightCornerUpYMod = 1.5;

        leftCornerLowXMod = -1.5;
        leftCornerLowYMod = -1.5


        cubeOX = 0;
        cubeOY = 0;
        cubeOZ = 0;

        cubeXX = 0;
        cubeXY = 0;
        cubeXZ = 0;
    end
    
    methods (Access = private)
        
        function winnerFound(app, string)
            app.WinnerFoundLamp.Color = [0, 1 ,0];
            app.TheWinnerIsEditField.Value = string;
            app.TheWinnerIsEditField.Visible = "on";
            drawnow
        end
        
        function winnerNotFound(app)
            app.WinnerFoundLamp.Color = [0, 0 ,0];
            app.TheWinnerIsEditField.Value = "Its a Tie";
            app.TheWinnerIsEditField.Visible = "on";
            drawnow
        end

        function turnIndicator(app, counter)
            if mod(counter, 2) == 0
                pathToMLAPP = fileparts(mfilename('fullpath'));
                app.TurnIndicator.ImageSource = fullfile(pathToMLAPP, 'o.png');
            else
                pathToMLAPP = fileparts(mfilename('fullpath'));
                app.TurnIndicator.ImageSource = fullfile(pathToMLAPP, 'close.png');
            end
            drawnow
        end

        function changeButtonStatus(app, ind, button)
            app.buttonsGame = [app.Position1 app.Position2 app.Position3 app.Position4 app.Position5 app.Position6 app.Position7 app.Position8 app.Position9];
            %**************************************************************
            mod2 = mod(app.counter, 2);
            oX=0.2;
            oY=0;
            oZ=0.1;
            xStackX=0.25;
            xStackY=0.17;
            xStackZ=0;
            yStackX=0.2;
            yStackY=0.17;
            yStackZ=0;
            %**********************************
                %try to make a loop for sequence
                if mod2 == 0 %get x from stack
                    Grippper=0.023;
                    app.sendGripper(Grippper)
                    pause(3);
                    x=oX;
                    y=oY;
                    z=oZ;
                    app.sendArmToPosition(x, y, z); %got to origin
                    %while get_param('Youbot2017_2016a/armStopped', 'RuntimeObject').OutputPort(1).Data;                   
                    %end
                    pause(5);
                    x=xStackX;
                    y=xStackY;
                    z=xStackZ;
                    app.sendArmToPosition(x, y, z); %move to stack
                    %while get_param('Youbot2017_2016a/armStopped', 'RuntimeObject').OutputPort(1).Data;                   
                    %end
                    pause(5);
                    z=(xStackZ-0.02-(app.counter*0.01));
                    app.sendArmToPosition(x, y, z); %decend to stack
                    %while get_param('Youbot2017_2016a/armStopped', 'RuntimeObject').OutputPort(1).Data;                  
                    %end
                    pause(5);
                    Grippper=0.002;
                    app.sendGripper(Grippper)
                    pause(3);
                    x=oX;
                    y=oY;
                    z=oZ;
                    app.sendArmToPosition(x, y, z); %got to origin
                    %while get_param('Youbot2017_2016a/armStopped', 'RuntimeObject').OutputPort(1).Data;                   
                    %end
                    pause(3);
                end
                
                if mod2 == 1 %get y from stack
                    Grippper=0.023;
                    app.sendGripper(Grippper)
                    pause(3);
                    x=oX;
                    y=oY;
                    z=oZ;
                    app.sendArmToPosition(x, y, z); %got to origin
                    %while get_param('Youbot2017_2016a/armStopped', 'RuntimeObject').OutputPort(1).Data;                    
                    %end
                    pause(5);
                    x=yStackX;
                    y=yStackY;
                    z=yStackZ;
                    app.sendArmToPosition(x, y, z); %move to stack
                    %while get_param('Youbot2017_2016a/armStopped', 'RuntimeObject').OutputPort(1).Data;                   
                    %end
                    pause(5);
                    z=(yStackZ-0.02-((app.counter+1)*0.01));
                    app.sendArmToPosition(x, y, z); %decend to stack
                    %while get_param('Youbot2017_2016a/armStopped', 'RuntimeObject').OutputPort(1).Data;                   
                    %end
                    pause(5);
                    Grippper=0.002;
                    app.sendGripper(Grippper)
                    pause(3);
                    x=oX;
                    y=oY;
                    z=oZ;
                    app.sendArmToPosition(x, y, z); %got to origin
                    %while get_param('Youbot2017_2016a/armStopped', 'RuntimeObject').OutputPort(1).Data;                   
                    %end
                    pause(3);
                end
                %**********************************
                %x = app.coordinatesOrigin(ind, 1); %move to cube stack on upper plane
                %    y = app.coordinatesOrigin(ind, 2);
                %    z = 0.2;
                %    app.sendArmToPosition(x, y, z);
                x = app.coordinatesOrigin(ind, 1); %move to cube stack on upper plane
                y = app.coordinatesOrigin(ind, 2);
                z = 0;
                app.sendArmToPosition(x, y, z); %move to cell
                    %while get_param('Youbot2017_2016a/armStopped', 'RuntimeObject').OutputPort(1).Data;                   
                    %end
                    pause(5);
                    z=(-0.10);
                    app.sendArmToPosition(x, y, z); %descend to cell
                    %while get_param('Youbot2017_2016a/armStopped', 'RuntimeObject').OutputPort(1).Data;                   
                    %end
                    pause(5);
                    Grippper=0.023;
                    app.sendGripper(Grippper)
                    pause(3);
                    z = 0;
                    app.sendArmToPosition(x, y, z); %got to origin
                    %while get_param('Youbot2017_2016a/armStopped', 'RuntimeObject').OutputPort(1).Data;                   
                    %end
                    pause(5);
            %**************************************************************
            if app.status(ind) == "0" && app.winner == 0
                pathToMLAPP = fileparts(mfilename('fullpath'));
                app.turnIndicator(app.counter);
                app.counter = app.counter + 1;
                if mod2 == 0
                    button.Icon = fullfile(pathToMLAPP, 'close.png');
                    app.status(ind) = "x";
                    app.positionsX(app.position(ind,1),app.position(ind,2)) = 1;
                    app.checkWinnerX();               
                else
                    button.Icon = fullfile(pathToMLAPP, 'o.png');
                    app.status(ind) = "o";
                    app.positionsO(app.position(ind,1),app.position(ind,2)) = 2;
                    app.checkWinnerO();
                end
     %**************************************************************


     %**************************************************************        
                drawnow
                if app.counter == 9 && app.winner == 0
                    app.winnerNotFound();
                end

%                 for c = 1:9
%                     app.buttonsGame(c).Enable = "off";
%                 end

                
            end

%             for c = 1:9
%                
%                 app.buttonsGame(c).Enable = "on";
%             end
        end

        function checkWinnerX(app)
            for c=1:3
                column = app.positionsX(:,c);
                equalcolumn = isequal(column, app.winningPositionsX2);
                if equalcolumn
                    app.winner = 1;
                    app.winnerFound('X')
                    break
                end
            end

            for c = 1:3
                row = app.positionsX(c,:);
                equalrow = isequal(row, app.winningPositionsX1);
                if equalrow
                    app.winner = 1;
                    app.winnerFound('X')
                    break
                end
            end
           

            diagonallyX1(1) = app.positionsX(1,1);
            diagonallyX2(1) = app.positionsX(1,3);

            diagonallyX1(2) = app.positionsX(2,2);
            diagonallyX2(2) = app.positionsX(2,2);

            diagonallyX1(3) = app.positionsX(3,3);
            diagonallyX2(3) = app.positionsX(3,1);

            equaldiagonallyX1 = isequal(diagonallyX1, app.winningPositionsX1);
            if equaldiagonallyX1
                app.winner = 1;
                app.winnerFound('X')
            end
            equaldiagonallyX2 = isequal(diagonallyX2, app.winningPositionsX1);
            if equaldiagonallyX2
                app.winner = 1;
                app.winnerFound('X')
            end

        end

        function checkWinnerO(app)
            for c=1:3
                column = app.positionsO(:,c);
                equalcolumn = isequal(column, app.winningPositionsO2);
                if equalcolumn
                    app.winner = 1;
                    app.winnerFound('O');
                    break
                end
            end
            for c = 1:3
                row = app.positionsO(c,:);
                equalrow = isequal(row, app.winningPositionsO1);
                if equalrow
                    app.winner = 1;
                    app.winnerFound('O')
                    break
                end
            end

            diagonallyO1(1) = app.positionsO(1,1);
            diagonallyO2(1) = app.positionsO(1,3);

            diagonallyO1(2) = app.positionsO(2,2);
            diagonallyO2(2) = app.positionsO(2,2);

            diagonallyO1(3) = app.positionsO(3,3);
            diagonallyO2(3) = app.positionsO(3,1);

            equaldiagonallyO1 = isequal(diagonallyO1, app.winningPositionsO1);
            if equaldiagonallyO1
                app.winner = 1;
                app.winnerFound('O');
            end
            equaldiagonallyO2 = isequal(diagonallyO2, app.winningPositionsO1);
            if equaldiagonallyO2
                app.winner = 1;
                app.winnerFound('O');
            end
        end

       
        function sendArmToPosition(app, x, y, z)
            %app.armStillMoving = 1;
            set_param('Youbot2017_2016a/x', 'Value', num2str(x));
            set_param('Youbot2017_2016a/y', 'Value', num2str(y));
            set_param('Youbot2017_2016a/z', 'Value', num2str(z));
            %set_param('Youbot2017_2016a/armMoves', 'Value', num2str(app.armStillMoving));
        end

        function sendGripper(app, Grippper)
            %app.armStillMoving = 1;
            set_param('Youbot2017_2016a/Grippper', 'Value', num2str(Grippper));
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Position1
        function Position1Pushed(app, event)
              app.changeButtonStatus(1, app.Position1);
        end

        % Button pushed function: MovetoCoordinatesButton
        function MovetoCoordinatesButtonPushed(app, event)
            valuex = app.xEditField.Value;
            valuey = app.yEditField.Value;
            valuez = app.zEditField.Value;
            set_param('Youbot2017_2016a/x', 'Value', num2str(valuex));
            set_param('Youbot2017_2016a/y', 'Value', num2str(valuey));
            set_param('Youbot2017_2016a/z', 'Value', num2str(valuez));
        end

        % Button pushed function: Position2
        function Position2Pushed(app, event)
            app.changeButtonStatus(2, app.Position2);
        end

        % Button pushed function: Position3
        function Position3Pushed(app, event)
            app.changeButtonStatus(3, app.Position3);
        end

        % Button pushed function: Position9
        function Position9ButtonPushed(app, event)
            app.changeButtonStatus(9, app.Position9);
        end

        % Button pushed function: Position8
        function Position8ButtonPushed(app, event)
            app.changeButtonStatus(8, app.Position8);
        end

        % Button pushed function: Position6
        function Position6ButtonPushed(app, event)
            app.changeButtonStatus(6, app.Position6);
        end

        % Button pushed function: Position5
        function Position5ButtonPushed(app, event)
            app.changeButtonStatus(5, app.Position5);
        end

        % Button pushed function: Position7
        function Position7ButtonPushed(app, event)
            app.changeButtonStatus(7, app.Position7);
        end

        % Button pushed function: Position4
        function Position4ButtonPushed(app, event)
            app.changeButtonStatus(4, app.Position4);
        end

        % Button pushed function: LoadSimulinkButton
        function LoadSimulinkButtonPushed(app, event)
            open_system('Youbot2017_2016a');
            set_param('Youbot2017_2016a','SimulationCommand','start');
        end

        % Button pushed function: ResetGameButton
        function ResetGameButtonPushed(app, event)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.counter = 0;
            app.status = ["0" "0" "0" "0" "0" "0" "0" "0" "0"];
            app.positionsX = zeros(3,3);
            app.positionsO = zeros(3,3);
            app.winner = 0;
            buttons = [app.Position1 app.Position2 app.Position3 app.Position4 app.Position5 app.Position6 app.Position7 app.Position8 app.Position9];
            for i = 1:9
                buttons(i).Icon = fullfile(pathToMLAPP, 'frame.png');
            end

            app.TurnIndicator.ImageSource = fullfile(pathToMLAPP, 'close.png');
            app.WinnerFoundLamp.Color = [1, 1 ,1];
            app.TheWinnerIsEditField.Value = "";
            app.TheWinnerIsEditField.Visible = "off";
            drawnow

        end

        % Value changed function: AutoplayOSwitch
        function AutoplayOSwitchValueChanged(app, event)
            value = app.AutoplayOSwitch.Value;
            app.autoplayO = value;
        end

        % Value changed function: AutoplayXSwitch
        function AutoplayXSwitchValueChanged(app, event)
            value = app.AutoplayXSwitch.Value;
            app.autoplayX = value;
        end

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
            app.buttonsGame = [app.Position1 app.Position2 app.Position3 app.Position4 app.Position5 app.Position6 app.Position7 app.Position8 app.Position9];
            app.stopGame = 0;
            if app.autoplayX == "On" || app.autoplayO == "On" 
              while isempty(find(app.status == "0", 1)) == 0 && app.winner == 0 && app.stopGame == 0
%                     if app.armStillMoving == 0
%                         for c = 1:9
%                             app.buttonsGame(c).Enable = "on";
%                         end
%                     else
%                         for c = 1:9
%                             app.buttonsGame(c).Enable = "off";
%                         end
%                     end
                    if app.autoplayX == "On" %&& app.armStillMoving == 0 
                        if mod(app.counter, 2) == 0
                            freeSpots = find(app.status == "0");
                            randameInd = randi(length(freeSpots));
                            app.changeButtonStatus(freeSpots(randameInd), app.buttonsGame(freeSpots(randameInd)));
                            %%app.sendArmToPosition();
                        end
                    end
                    if app.autoplayO == "On" %&& app.armStillMoving == 0
                        if mod(app.counter+1, 2) == 0
                            freeSpots = find(app.status == "0");
                            randameInd = randi(length(freeSpots));
                            app.changeButtonStatus(freeSpots(randameInd), app.buttonsGame(freeSpots(randameInd)));
                            %%app.sendArmToPosition();
                        end
                    end
                    %drawnow update;
                    %pause(0.5);
                    %app.armStillMoving = str2num(get_param('Youbot2017_2016a/armMoves', 'Value')); %%set to parameter that is set to 0 when moving sequence is done
                    %get_param('Youbot2017_2016a/armStopped', 'RuntimeObject').OutputPort(1).Data;
                 end
             end
        end

        % Button pushed function: StopGameButton
        function StopGameButtonPushed(app, event)
            app.stopGame = 1;
        end

        % Button pushed function: LeftCornerRefButton
        function LeftCornerRefButtonPushed(app, event)
            app.leftCornerLowXMod = str2num(get_param('Youbot2017_2016a/x', 'Value')); %%Get Real coordinates from robot later
            app.leftCornerLowYMod = str2num(get_param('Youbot2017_2016a/y', 'Value'));
            assignin('base','leftCornerLowXMod',app.leftCornerLowXMod);
            assignin('base','leftCornerLowYMod',app.leftCornerLowYMod);
        end

        % Button pushed function: RightCornerRefButton
        function RightCornerRefButtonPushed(app, event)
            app.rightCornerUpXMod = str2num(get_param('Youbot2017_2016a/x', 'Value'));
            app.rightCornerUpYMod = str2num(get_param('Youbot2017_2016a/y', 'Value'));
            assignin('base','rightCornerUpXMod',app.rightCornerUpXMod);
            assignin('base','rightCornerUpYMod',app.rightCornerUpYMod);
        end

        % Button pushed function: LoadReferencesButton
        function LoadReferencesButtonPushed(app, event)
            app.leftCornerLowXMod = evalin('base','leftCornerLowXMod');
            app.leftCornerLowYMod = evalin('base','leftCornerLowYMod');
            app.rightCornerUpXMod = evalin('base','rightCornerUpXMod');
            app.rightCornerUpYMod = evalin('base','rightCornerUpYMod');

            app.cubeXX = evalin('base','cubeXX');
            app.cubeXY = evalin('base','cubeXY');
            app.cubeXZ = evalin('base','cubeXZ');

            app.cubeOX = evalin('base','cubeOX');
            app.cubeOY = evalin('base','cubeOY');
            app.cubeOZ = evalin('base','cubeOZ');

            disp(app.cubeOX)

        end

        % Callback function
        function PlotButtonPushed(app, event)
            
            L(1)=Link([ 0.1 1 0 0]);
            L(2)=Link([ 0.1 1 0 0]);
            L(3)=Link([ 0 0 1 0]);
            L(4)=Link([ 0 0 1 0]);
            L(5)=Link([ 0 0 1 0]);
            AngleOffset=[0 0 0 0 0];
            r=SerialLink(L,'name','YouBot Manipulator Arm','offset',AngleOffset);
            axes(app.UIAxes)
            r.plot([0 pi/4 0 0 0])
            drawnow
        end

        % Button pushed function: StackORefButton
        function StackORefButtonPushed(app, event)
            app.cubeOX = str2num(get_param('Youbot2017_2016a/x', 'Value'));
            app.cubeOY = str2num(get_param('Youbot2017_2016a/y', 'Value'));
            app.cubeOZ = str2num(get_param('Youbot2017_2016a/z', 'Value'));
            assignin('base','cubeOX',app.cubeOX);
            assignin('base','cubeOY',app.cubeOY);
            assignin('base','cubeOZ',app.cubeOZ);
        end

        % Button pushed function: StackXRefButton
        function StackXRefButtonPushed(app, event)
            app.cubeXX = str2num(get_param('Youbot2017_2016a/x', 'Value'));
            app.cubeXY = str2num(get_param('Youbot2017_2016a/y', 'Value'));
            app.cubeXZ = str2num(get_param('Youbot2017_2016a/z', 'Value'));
            assignin('base','cubeXX',app.cubeXX);
            assignin('base','cubeXY',app.cubeXY);
            assignin('base','cubeXZ',app.cubeXZ);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TicTacToePanel = uipanel(app.UIFigure);
            app.TicTacToePanel.Title = 'Tic Tac Toe';
            app.Position1 = uibutton(app.TicTacToePanel, 'push');
            app.Position1.ButtonPushedFcn = createCallbackFcn(app, @Position1Pushed, true);
            app.Position1.Icon = fullfile(pathToMLAPP, 'frame.png');
            app.Position1.Text = '1';
            app.Position2 = uibutton(app.TicTacToePanel, 'push');
            app.Position2.ButtonPushedFcn = createCallbackFcn(app, @Position2Pushed, true);
            app.Position2.Icon = fullfile(pathToMLAPP, 'frame.png');
            app.Position2.Text = '2';
            app.Position3 = uibutton(app.TicTacToePanel, 'push');
            app.Position3.ButtonPushedFcn = createCallbackFcn(app, @Position3Pushed, true);
            app.Position3.Icon = fullfile(pathToMLAPP, 'frame.png');
            app.Position3.Text = '3';
            app.Position4 = uibutton(app.TicTacToePanel, 'push');
            app.Position4.ButtonPushedFcn = createCallbackFcn(app, @Position4ButtonPushed, true);
            app.Position4.Icon = fullfile(pathToMLAPP, 'frame.png');
            app.Position4.Text = '4';
            app.Position5 = uibutton(app.TicTacToePanel, 'push');
            app.Position5.ButtonPushedFcn = createCallbackFcn(app, @Position5ButtonPushed, true);
            app.Position5.Icon = fullfile(pathToMLAPP, 'frame.png');
            app.Position5.Text = '5';
            app.Position6 = uibutton(app.TicTacToePanel, 'push');
            app.Position6.ButtonPushedFcn = createCallbackFcn(app, @Position6ButtonPushed, true);
            app.Position6.Icon = fullfile(pathToMLAPP, 'frame.png');
            app.Position6.Text = '6';
            app.Position7 = uibutton(app.TicTacToePanel, 'push');
            app.Position7.ButtonPushedFcn = createCallbackFcn(app, @Position7ButtonPushed, true);
            app.Position7.Icon = fullfile(pathToMLAPP, 'frame.png');
            app.Position7.Text = '7';
            app.Position8 = uibutton(app.TicTacToePanel, 'push');
            app.Position8.ButtonPushedFcn = createCallbackFcn(app, @Position8ButtonPushed, true);
            app.Position8.Icon = fullfile(pathToMLAPP, 'frame.png');
            app.Position8.Text = '8';
            app.Position9 = uibutton(app.TicTacToePanel, 'push');
            app.Position9.ButtonPushedFcn = createCallbackFcn(app, @Position9ButtonPushed, true);
            app.Position9.Icon = fullfile(pathToMLAPP, 'frame.png');
            app.Position9.Text = '9';
            app.WinnerPanel = uipanel(app.UIFigure);
            app.WinnerPanel.Title = 'Winner';
            app.WinnerFoundLampLabel = uilabel(app.WinnerPanel);
            app.WinnerFoundLampLabel.Text = 'Winner  Found';
            app.WinnerFoundLamp = uilamp(app.WinnerPanel);
            app.TheWinnerIsEditFieldLabel = uilabel(app.WinnerPanel);
            app.TheWinnerIsEditFieldLabel.Text = 'The Winner Is';
            app.TheWinnerIsEditField = uieditfield(app.WinnerPanel, 'text');
            app.TheWinnerIsEditField.Visible = 'off';
            app.ItisyourturnPanel = uipanel(app.UIFigure);
            app.ItisyourturnPanel.Title = 'It is your turn ';
            app.TurnIndicator = uiimage(app.ItisyourturnPanel);
            app.TurnIndicator.ImageSource = fullfile(pathToMLAPP, 'close.png');
            app.ResetGameButton = uibutton(app.UIFigure, 'push');
            app.ResetGameButton.ButtonPushedFcn = createCallbackFcn(app, @ResetGameButtonPushed, true);
            app.ResetGameButton.Text = 'Reset Game';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control';
            app.AutoplayXSwitchLabel = uilabel(app.ControlPanel);
            app.AutoplayXSwitchLabel.Text = 'Autoplay X';
            app.AutoplayXSwitch = uiswitch(app.ControlPanel, 'slider');
            app.AutoplayXSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoplayXSwitchValueChanged, true);
            app.LoadSimulinkButton = uibutton(app.ControlPanel, 'push');
            app.LoadSimulinkButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSimulinkButtonPushed, true);
            app.LoadSimulinkButton.Text = 'Load Simulink';
            app.AutoplayOSwitchLabel = uilabel(app.ControlPanel);
            app.AutoplayOSwitchLabel.Text = 'Autoplay O';
            app.AutoplayOSwitch = uiswitch(app.ControlPanel, 'slider');
            app.AutoplayOSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoplayOSwitchValueChanged, true);
            app.MoveManuallyPanel = uipanel(app.ControlPanel);
            app.MoveManuallyPanel.Title = 'Move Manually';
            app.xEditFieldLabel = uilabel(app.MoveManuallyPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.MoveManuallyPanel, 'numeric');
            app.yEditFieldLabel = uilabel(app.MoveManuallyPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.MoveManuallyPanel, 'numeric');
            app.zEditFieldLabel = uilabel(app.MoveManuallyPanel);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.MoveManuallyPanel, 'numeric');
            app.MovetoCoordinatesButton = uibutton(app.MoveManuallyPanel, 'push');
            app.MovetoCoordinatesButton.ButtonPushedFcn = createCallbackFcn(app, @MovetoCoordinatesButtonPushed, true);
            app.MovetoCoordinatesButton.Text = 'Move to Coordinates';
            app.q1EditFieldLabel = uilabel(app.MoveManuallyPanel);
            app.q1EditFieldLabel.Text = 'q1';
            app.q1EditField = uieditfield(app.MoveManuallyPanel, 'numeric');
            app.q2EditFieldLabel = uilabel(app.MoveManuallyPanel);
            app.q2EditFieldLabel.Text = 'q2';
            app.q2EditField = uieditfield(app.MoveManuallyPanel, 'numeric');
            app.q5EditFieldLabel = uilabel(app.MoveManuallyPanel);
            app.q5EditFieldLabel.Text = 'q5';
            app.q5EditField = uieditfield(app.MoveManuallyPanel, 'numeric');
            app.q3EditFieldLabel = uilabel(app.MoveManuallyPanel);
            app.q3EditFieldLabel.Text = 'q3';
            app.q3EditField = uieditfield(app.MoveManuallyPanel, 'numeric');
            app.q4EditFieldLabel = uilabel(app.MoveManuallyPanel);
            app.q4EditFieldLabel.Text = 'q4';
            app.q4EditField = uieditfield(app.MoveManuallyPanel, 'numeric');
            app.MovetoAnglesButton = uibutton(app.MoveManuallyPanel, 'push');
            app.MovetoAnglesButton.Text = 'Move to Angles';
            app.RightCornerRefButton = uibutton(app.MoveManuallyPanel, 'push');
            app.RightCornerRefButton.ButtonPushedFcn = createCallbackFcn(app, @RightCornerRefButtonPushed, true);
            app.RightCornerRefButton.Text = 'Right Corner Ref';
            app.LeftCornerRefButton = uibutton(app.MoveManuallyPanel, 'push');
            app.LeftCornerRefButton.ButtonPushedFcn = createCallbackFcn(app, @LeftCornerRefButtonPushed, true);
            app.LeftCornerRefButton.Text = 'Left Corner Ref';
            app.LoadReferencesButton = uibutton(app.MoveManuallyPanel, 'push');
            app.LoadReferencesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadReferencesButtonPushed, true);
            app.LoadReferencesButton.Text = 'Load References';
            app.StackXRefButton = uibutton(app.MoveManuallyPanel, 'push');
            app.StackXRefButton.ButtonPushedFcn = createCallbackFcn(app, @StackXRefButtonPushed, true);
            app.StackXRefButton.Text = 'Stack X Ref';
            app.StackORefButton = uibutton(app.MoveManuallyPanel, 'push');
            app.StackORefButton.ButtonPushedFcn = createCallbackFcn(app, @StackORefButtonPushed, true);
            app.StackORefButton.Text = 'Stack O Ref';
            app.StartGameButton = uibutton(app.ControlPanel, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Start Game';
            app.StopGameButton = uibutton(app.ControlPanel, 'push');
            app.StopGameButton.ButtonPushedFcn = createCallbackFcn(app, @StopGameButtonPushed, true);
            app.StopGameButton.Text = 'Stop Game';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end