classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ItisyourturnPanel          matlab.ui.container.Panel
        TurnIndicator              matlab.ui.control.Image
        WinnerPanel                matlab.ui.container.Panel
        TheWinnerIsEditField       matlab.ui.control.EditField
        TheWinnerIsEditFieldLabel  matlab.ui.control.Label
        WinnerFoundLamp            matlab.ui.control.Lamp
        WinnerFoundLampLabel       matlab.ui.control.Label
        StartButton                matlab.ui.control.Button
        TicTacToePanel             matlab.ui.container.Panel
        Position9                  matlab.ui.control.Button
        Position8                  matlab.ui.control.Button
        Position7                  matlab.ui.control.Button
        Position6                  matlab.ui.control.Button
        Position5                  matlab.ui.control.Button
        Position4                  matlab.ui.control.Button
        Position3                  matlab.ui.control.Button
        Position2                  matlab.ui.control.Button
        Position1                  matlab.ui.control.Button
        MovetoCoordinatesButton    matlab.ui.control.Button
        zEditField                 matlab.ui.control.NumericEditField
        zEditFieldLabel            matlab.ui.control.Label
        yEditField                 matlab.ui.control.NumericEditField
        yEditFieldLabel            matlab.ui.control.Label
        xEditField                 matlab.ui.control.NumericEditField
        xEditFieldLabel            matlab.ui.control.Label
    end


    properties (Access = private)
        counter = 0;
        status1 = "0";
        status2 = "0";
        status3 = "0";
        status4 = "0";
        status5 = "0";
        status6 = "0";
        status7 = "0";
        status8 = "0";
        status9 = "0";
        winningPositionsX1 = [1, 1, 1]
        winningPositionsX2 = [1; 1; 1];
        winningPositionsO1 = [2, 2, 2]
        winningPositionsO2 = [2; 2; 2];
        positionsX = zeros(3,3);
        positionsO = zeros(3,3);
        winner;
    end
    
    methods (Access = private)
        
        function winnerFound(app, string)
            app.WinnerFoundLamp.Color = [0, 1 ,0];
            app.TheWinnerIsEditField.Value = string;
            app.TheWinnerIsEditField.Visible = "on";
            drawnow
        end

        function turnIndicator(app, counter)
            if mod(counter, 2) == 0
                pathToMLAPP = fileparts(mfilename('fullpath'));
                app.TurnIndicator.ImageSource = fullfile(pathToMLAPP, 'o.png');
            else
                pathToMLAPP = fileparts(mfilename('fullpath'));
                app.TurnIndicator.ImageSource = fullfile(pathToMLAPP, 'close.png');
            end
            drawnow
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Position1
        function Position1Pushed(app, event)
            if app.status1 == "0"
                pathToMLAPP = fileparts(mfilename('fullpath'));
                mod2 = mod(app.counter, 2);
                app.turnIndicator(app.counter);
                app.counter = app.counter + 1;
                if mod2 == 0
                    app.Position1.Icon = fullfile(pathToMLAPP, 'close.png');
                    app.status1 = "x";
                    app.positionsX(1,1) = 1;
                    for c=1:3
                        column = app.positionsX(:,c);
                        equalcolumn = isequal(column, app.winningPositionsX2);
                        if equalcolumn
                            app.winnerFound('X')
                            break
                        end
                    end
                    for c = 1:3
                        row = app.positionsX(c,:);
                        equalrow = isequal(row, app.winningPositionsX1);
                        if equalrow
                            app.winnerFound('X')
                            break
                        end
                    end
                    
                else
                    app.Position1.Icon = fullfile(pathToMLAPP, 'o.png');
                    app.status1 = "o";
                    app.positionsO(1,1) = 2;
                    for c=1:3
                        column = app.positionsO(:,c);
                        equalcolumn = isequal(column, app.winningPositionsO2);
                        if equalcolumn
                            app.winnerFound('O')
                            break
                        end
                    end
                    for c = 1:3
                        row = app.positionsO(c,:);
                        equalrow = isequal(row, app.winningPositionsO1);
                        if equalrow
                            app.winnerFound('O')
                            break
                        end
                    end
                end
                
                drawnow
            end
        end

        % Button pushed function: MovetoCoordinatesButton
        function MovetoCoordinatesButtonPushed(app, event)
            valuex = app.xEditField.Value;
            valuey = app.yEditField.Value;
            valuez = app.zEditField.Value;
            %%set_param('Inverse/x', 'Value', num2str(valuex));
            %%set_param('Inverse/y', 'Value', num2str(valuey));
            %%set_param('Inverse/z', 'Value', num2str(valuez));
        end

        % Button pushed function: Position2
        function Position2Pushed(app, event)
            if app.status2 == "0"
                pathToMLAPP = fileparts(mfilename('fullpath'));
                mod2 = mod(app.counter, 2);
                app.turnIndicator(app.counter);
                app.counter = app.counter + 1;
                if mod2 == 0
                    app.Position2.Icon = fullfile(pathToMLAPP, 'close.png');
                    app.status2 = "x";
                    app.positionsX(1,2) = 1;
                    for c=1:3
                        column = app.positionsX(:,c);
                        equalcolumn = isequal(column, app.winningPositionsX2);
                        if equalcolumn
                            app.winnerFound('X')
                            break
                        end
                    end
                    for c = 1:3
                        row = app.positionsX(c,:);
                        equalrow = isequal(row, app.winningPositionsX1);
                        if equalrow
                            app.winnerFound('X')
                            break
                        end
                    end
                else
                    app.Position2.Icon = fullfile(pathToMLAPP, 'o.png');
                    app.status2 = "o";
                    app.positionsO(1,2) = 2;
                    for c=1:3
                        column = app.positionsO(:,c);
                        equalcolumn = isequal(column, app.winningPositionsO2);
                        if equalcolumn
                            app.winnerFound('O')
                            break
                        end
                    end
                    for c = 1:3
                        row = app.positionsO(c,:);
                        equalrow = isequal(row, app.winningPositionsO1);
                        if equalrow
                            app.winnerFound('O')
                            break
                        end
                    end
                end
                drawnow
            end
        end

        % Button pushed function: Position3
        function Position3Pushed(app, event)
            if app.status3 == "0"
                pathToMLAPP = fileparts(mfilename('fullpath'));
                mod2 = mod(app.counter, 2);
                app.turnIndicator(app.counter);
                app.counter = app.counter + 1;
                if mod2 == 0
                    app.Position3.Icon = fullfile(pathToMLAPP, 'close.png');
                    app.status3 = "x";
                    app.positionsX(1,3) = 1;
                    for c=1:3
                        column = app.positionsX(:,c);
                        equalcolumn = isequal(column, app.winningPositionsX2);
                        if equalcolumn
                            app.winnerFound('X')
                            break
                        end
                    end
                    for c = 1:3
                        row = app.positionsX(c,:);
                        equalrow = isequal(row, app.winningPositionsX1);
                        if equalrow
                            app.winnerFound('X')
                            break
                        end
                    end
                    
                else
                    app.Position3.Icon = fullfile(pathToMLAPP, 'o.png');
                    app.status3 = "o";
                    app.positionsO(1,3) = 2;
                    for c=1:3
                        column = app.positionsO(:,c);
                        equalcolumn = isequal(column, app.winningPositionsO2);
                        if equalcolumn
                            app.winnerFound('O')
                            break
                        end
                    end
                    for c = 1:3
                        row = app.positionsO(c,:);
                        equalrow = isequal(row, app.winningPositionsO1);
                        if equalrow
                            app.winnerFound('O')
                            break
                        end
                    end
                end
                drawnow
            end
        end

        % Button pushed function: Position9
        function Position9ButtonPushed(app, event)
            if app.status9 == "0"
                pathToMLAPP = fileparts(mfilename('fullpath'));
                mod2 = mod(app.counter, 2);
                app.turnIndicator(app.counter);
                app.counter = app.counter + 1;
                if mod2 == 0
                    app.Position9.Icon = fullfile(pathToMLAPP, 'close.png');
                    app.status9 = "x";
                    app.positionsX(3,3) = 1;
                    for c=1:3
                        column = app.positionsX(:,c);
                        equalcolumn = isequal(column, app.winningPositionsX2);
                        if equalcolumn
                            app.winnerFound('X')
                            break
                        end
                    end
                    for c = 1:3
                        row = app.positionsX(c,:);
                        equalrow = isequal(row, app.winningPositionsX1);
                        if equalrow
                            app.winnerFound('X')
                            break
                        end
                    end
                else
                    app.Position9.Icon = fullfile(pathToMLAPP, 'o.png');
                    app.status9 = "o";
                    app.positionsO(3,3) = 2;
                    for c=1:3
                        column = app.positionsO(:,c);
                        equalcolumn = isequal(column, app.winningPositionsO2);
                        if equalcolumn
                            app.winnerFound('O')
                            break
                        end
                    end
                    for c = 1:3
                        row = app.positionsO(c,:);
                        equalrow = isequal(row, app.winningPositionsO1);
                        if equalrow
                            app.winnerFound('O')
                            break
                        end
                    end
                    
                end
                drawnow
            end
        end

        % Button pushed function: Position8
        function Position8ButtonPushed(app, event)
            if app.status8 == "0"
                pathToMLAPP = fileparts(mfilename('fullpath'));
                mod2 = mod(app.counter, 2);
                app.turnIndicator(app.counter);
                app.counter = app.counter + 1;
                if mod2 == 0
                    app.Position8.Icon = fullfile(pathToMLAPP, 'close.png');
                    app.status8 = "x";
                    app.positionsX(3,2) = 1;
                    for c=1:3
                        column = app.positionsX(:,c);
                        equalcolumn = isequal(column, app.winningPositionsX2);
                        if equalcolumn
                            app.winnerFound('X')
                            break
                        end
                    end
                    for c = 1:3
                        row = app.positionsX(c,:);
                        equalrow = isequal(row, app.winningPositionsX1);
                        if equalrow
                            app.winnerFound('X')
                            break
                        end
                    end
                else
                    app.Position8.Icon = fullfile(pathToMLAPP, 'o.png');
                    app.status8 = "o";
                    app.positionsO(3,2) = 2;
                    for c=1:3
                        column = app.positionsO(:,c);
                        equalcolumn = isequal(column, app.winningPositionsO2);
                        if equalcolumn
                            app.winnerFound('O')
                            break
                        end
                    end
                    for c = 1:3
                        row = app.positionsO(c,:);
                        equalrow = isequal(row, app.winningPositionsO1);
                        if equalrow
                            app.winnerFound('O')
                            break
                        end
                    end
                end
                drawnow
            end
        end

        % Button pushed function: Position6
        function Position6ButtonPushed(app, event)
            if app.status6 == "0"
                pathToMLAPP = fileparts(mfilename('fullpath'));
                mod2 = mod(app.counter, 2);
                app.turnIndicator(app.counter);
                app.counter = app.counter + 1;
                if mod2 == 0
                    app.Position6.Icon = fullfile(pathToMLAPP, 'close.png');
                    app.status6 = "x";
                    app.positionsX(2,3) = 1;
                    for c=1:3
                        column = app.positionsX(:,c);
                        equalcolumn = isequal(column, app.winningPositionsX2);
                        if equalcolumn
                            app.winnerFound('X')
                            break
                        end
                    end
                    for c = 1:3
                        row = app.positionsX(c,:);
                        equalrow = isequal(row, app.winningPositionsX1);
                        if equalrow
                            app.winnerFound('X')
                            break
                        end
                    end
                else
                    app.Position6.Icon = fullfile(pathToMLAPP, 'o.png');
                    app.status6 = "o";
                    app.positionsO(2,3) = 2;
                    for c=1:3
                        column = app.positionsO(:,c);
                        equalcolumn = isequal(column, app.winningPositionsO2);
                        if equalcolumn
                            app.winnerFound('O')
                            break
                        end
                    end
                    for c = 1:3
                        row = app.positionsO(c,:);
                        equalrow = isequal(row, app.winningPositionsO1);
                        if equalrow
                            app.winnerFound('O')
                            break
                        end
                    end
                end
                drawnow
            end
        end

        % Button pushed function: Position5
        function Position5ButtonPushed(app, event)
            if app.status5 == "0"
                pathToMLAPP = fileparts(mfilename('fullpath'));
                mod2 = mod(app.counter, 2);
                app.turnIndicator(app.counter);
                app.counter = app.counter + 1;
                if mod2 == 0
                    app.Position5.Icon = fullfile(pathToMLAPP, 'close.png');
                    app.status5 = "x";
                    app.positionsX(2,2) = 1;
                    for c=1:3
                        column = app.positionsX(:,c);
                        equalcolumn = isequal(column, app.winningPositionsX2);
                        if equalcolumn
                            app.winnerFound('X')
                            break
                        end
                    end
                    for c = 1:3
                        row = app.positionsX(c,:);
                        equalrow = isequal(row, app.winningPositionsX1);
                        if equalrow
                            app.winnerFound('X')
                            break
                        end
                    end
                else
                    app.Position5.Icon = fullfile(pathToMLAPP, 'o.png');
                    app.status5 = "o";
                    app.positionsO(2,2) = 2;
                    for c=1:3
                        column = app.positionsO(:,c);
                        equalcolumn = isequal(column, app.winningPositionsO2);
                        if equalcolumn
                            app.winnerFound('O')
                            break
                        end
                    end
                    for c = 1:3
                        row = app.positionsO(c,:);
                        equalrow = isequal(row, app.winningPositionsO1);
                        if equalrow
                            app.winnerFound('O')
                            break
                        end
                    end
                end
                drawnow
            end
        end

        % Button pushed function: Position7
        function Position7ButtonPushed(app, event)
            if app.status7 == "0"
                pathToMLAPP = fileparts(mfilename('fullpath'));
                mod2 = mod(app.counter, 2);
                app.turnIndicator(app.counter);
                app.counter = app.counter + 1;
                if mod2 == 0
                    app.Position7.Icon = fullfile(pathToMLAPP, 'close.png');
                    app.status7 = "x";
                    app.positionsX(3,1) = 1;
                    for c=1:3
                        column = app.positionsX(:,c);
                        equalcolumn = isequal(column, app.winningPositionsX2);
                        if equalcolumn
                            app.winnerFound('X')
                            break
                        end
                    end
                    for c = 1:3
                        row = app.positionsX(c,:);
                        equalrow = isequal(row, app.winningPositionsX1);
                        if equalrow
                            app.winnerFound('X')
                            break
                        end
                    end
                else
                    app.Position7.Icon = fullfile(pathToMLAPP, 'o.png');
                    app.status7 = "o";
                    app.positionsO(3,1) = 2;
                    for c=1:3
                        column = app.positionsO(:,c);
                        equalcolumn = isequal(column, app.winningPositionsO2);
                        if equalcolumn
                            app.winnerFound('O')
                            break
                        end
                    end
                    for c = 1:3
                        row = app.positionsO(c,:);
                        equalrow = isequal(row, app.winningPositionsO1);
                        if equalrow
                            app.winnerFound('O')
                            break
                        end
                    end
                end
                drawnow
            end
        end

        % Button pushed function: Position4
        function Position4ButtonPushed(app, event)
            if app.status4 == "0"
                pathToMLAPP = fileparts(mfilename('fullpath'));
                mod2 = mod(app.counter, 2);
                app.turnIndicator(app.counter);
                app.counter = app.counter + 1;
                if mod2 == 0
                    app.Position4.Icon = fullfile(pathToMLAPP, 'close.png');
                    app.status4 = "x";
                    app.positionsX(2,1) = 1;
                    for c=1:3
                        column = app.positionsX(:,c);
                        equalcolumn = isequal(column, app.winningPositionsX2);
                        if equalcolumn
                            app.winnerFound('X')
                            break
                        end
                    end
                    for c = 1:3
                        row = app.positionsX(c,:);
                        equalrow = isequal(row, app.winningPositionsX1);
                        if equalrow
                            app.winnerFound('X')
                            break
                        end
                    end
                else
                    app.Position4.Icon = fullfile(pathToMLAPP, 'o.png');
                    app.status4 = "o";
                    app.positionsO(2,1) = 2;
                    for c=1:3
                        column = app.positionsO(:,c);
                        equalcolumn = isequal(column, app.winningPositionsO2);
                        if equalcolumn
                            app.winnerFound('O')
                            break
                        end
                    end
                    for c = 1:3
                        row = app.positionsO(c,:);
                        equalrow = isequal(row, app.winningPositionsO1);
                        if equalrow
                            app.winnerFound('O')
                            break
                        end
                    end
                end
                drawnow
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            open_system('Inverse');
            set_param('Inverse','SimulationCommand','start');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'numeric');
            app.MovetoCoordinatesButton = uibutton(app.UIFigure, 'push');
            app.MovetoCoordinatesButton.ButtonPushedFcn = createCallbackFcn(app, @MovetoCoordinatesButtonPushed, true);
            app.MovetoCoordinatesButton.Text = 'Move to Coordinates';
            app.TicTacToePanel = uipanel(app.UIFigure);
            app.TicTacToePanel.Title = 'Tic Tac Toe';
            app.Position1 = uibutton(app.TicTacToePanel, 'push');
            app.Position1.ButtonPushedFcn = createCallbackFcn(app, @Position1Pushed, true);
            app.Position1.Icon = fullfile(pathToMLAPP, 'frame.png');
            app.Position1.Text = '1';
            app.Position2 = uibutton(app.TicTacToePanel, 'push');
            app.Position2.ButtonPushedFcn = createCallbackFcn(app, @Position2Pushed, true);
            app.Position2.Icon = fullfile(pathToMLAPP, 'frame.png');
            app.Position2.Text = '2';
            app.Position3 = uibutton(app.TicTacToePanel, 'push');
            app.Position3.ButtonPushedFcn = createCallbackFcn(app, @Position3Pushed, true);
            app.Position3.Icon = fullfile(pathToMLAPP, 'frame.png');
            app.Position3.Text = '3';
            app.Position4 = uibutton(app.TicTacToePanel, 'push');
            app.Position4.ButtonPushedFcn = createCallbackFcn(app, @Position4ButtonPushed, true);
            app.Position4.Icon = fullfile(pathToMLAPP, 'frame.png');
            app.Position4.Text = '4';
            app.Position5 = uibutton(app.TicTacToePanel, 'push');
            app.Position5.ButtonPushedFcn = createCallbackFcn(app, @Position5ButtonPushed, true);
            app.Position5.Icon = fullfile(pathToMLAPP, 'frame.png');
            app.Position5.Text = '5';
            app.Position6 = uibutton(app.TicTacToePanel, 'push');
            app.Position6.ButtonPushedFcn = createCallbackFcn(app, @Position6ButtonPushed, true);
            app.Position6.Icon = fullfile(pathToMLAPP, 'frame.png');
            app.Position6.Text = '6';
            app.Position7 = uibutton(app.TicTacToePanel, 'push');
            app.Position7.ButtonPushedFcn = createCallbackFcn(app, @Position7ButtonPushed, true);
            app.Position7.Icon = fullfile(pathToMLAPP, 'frame.png');
            app.Position7.Text = '7';
            app.Position8 = uibutton(app.TicTacToePanel, 'push');
            app.Position8.ButtonPushedFcn = createCallbackFcn(app, @Position8ButtonPushed, true);
            app.Position8.Icon = fullfile(pathToMLAPP, 'frame.png');
            app.Position8.Text = '8';
            app.Position9 = uibutton(app.TicTacToePanel, 'push');
            app.Position9.ButtonPushedFcn = createCallbackFcn(app, @Position9ButtonPushed, true);
            app.Position9.Icon = fullfile(pathToMLAPP, 'frame.png');
            app.Position9.Text = '9';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.WinnerPanel = uipanel(app.UIFigure);
            app.WinnerPanel.Title = 'Winner';
            app.WinnerFoundLampLabel = uilabel(app.WinnerPanel);
            app.WinnerFoundLampLabel.Text = 'Winner  Found';
            app.WinnerFoundLamp = uilamp(app.WinnerPanel);
            app.TheWinnerIsEditFieldLabel = uilabel(app.WinnerPanel);
            app.TheWinnerIsEditFieldLabel.Text = 'The Winner Is';
            app.TheWinnerIsEditField = uieditfield(app.WinnerPanel, 'text');
            app.TheWinnerIsEditField.Visible = 'off';
            app.ItisyourturnPanel = uipanel(app.UIFigure);
            app.ItisyourturnPanel.Title = 'It is your turn ';
            app.TurnIndicator = uiimage(app.ItisyourturnPanel);
            app.TurnIndicator.ImageSource = fullfile(pathToMLAPP, 'close.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end